classdef AC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % Aires ...
        LabelEditField          matlab.ui.control.Label            % Curva ...
        EditFieldFileT          matlab.ui.control.EditField       
        LabelNumericEditField   matlab.ui.control.Label            % Temper...
        NumericEditFieldTempIni matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField2  matlab.ui.control.Label            % Perdid...
        NumericEditFieldEpsilon matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField3  matlab.ui.control.Label            % Rendim...
        NumericEditFieldEta     matlab.ui.control.NumericEditField % [-Inf ...
        ButtonGuardar           matlab.ui.control.Button           % Guardar
        ButtonCancelar          matlab.ui.control.Button           % Cancelar
    end


    properties (Access = public)
        AiCon % Description
        Principal
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % ButtonGuardar button pushed function
        function ButtonGuardar_ButtonPushed(app)
            app.AiCon.fileT = app.EditFieldFileT.Value;
            app.AiCon.tempIni = app.NumericEditFieldTempIni.Value;
            app.AiCon.epsilon = app.NumericEditFieldEpsilon.Value;
            app.AiCon.eta = app.NumericEditFieldEta.Value;
            updateAC(app.Principal,app.AiCon);
            delete(app)
            
            
        end

        % ButtonCancelar button pushed function
        function ButtonCancelar_ButtonPushed(app)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Aires Acondicionados';
            setAutoResize(app, app.UIFigure, true)
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Curva de Temperaturas Preferidas';
            app.EditFieldFileT = uieditfield(app.UIFigure, 'text');
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Temperatura Inicial';
            app.NumericEditFieldTempIni = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Perdidas por conductividad termica';
            app.NumericEditFieldEpsilon = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'Rendimiento de potencia';
            app.NumericEditFieldEta = uieditfield(app.UIFigure, 'numeric');
            app.ButtonGuardar = uibutton(app.UIFigure, 'push');
            app.ButtonGuardar.ButtonPushedFcn = createCallbackFcn(app, @ButtonGuardar_ButtonPushed);
            app.ButtonGuardar.Text = 'Guardar';
            app.ButtonCancelar = uibutton(app.UIFigure, 'push');
            app.ButtonCancelar.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelar_ButtonPushed);
            app.ButtonCancelar.Text = 'Cancelar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AC()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end