classdef Bateria < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % Bateria
        LabelNumericEditField  matlab.ui.control.Label            % Nodo
        NumericEditFieldNodo   matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelDropDown2         matlab.ui.control.Label            % Tipo de...
        DropDownType           matlab.ui.control.DropDown         % bat_def
        LabelNumericEditField2 matlab.ui.control.Label            % Energia...
        NumericEditFieldEIni   matlab.ui.control.NumericEditField % [-Inf Inf]
        ButtonGuardar          matlab.ui.control.Button           % Guardar
        ButtonCancelar         matlab.ui.control.Button           % Cancelar
    end


    properties (Access = public)
        Bat % Description
        Principal
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % ButtonGuardar button pushed function
        function ButtonGuardar_ButtonPushed(app)
            app.Bat.nod = app.NumericEditFieldNodo.Value;
            app.Bat.type = ['casos\bat\' app.DropDownType.Value '.csv'];
            app.Bat.EIni = app.NumericEditFieldEIni.Value;
            updateBat(app.Principal,app.Bat);
            delete(app)            
        end

        % ButtonCancelar button pushed function
        function ButtonCancelar_ButtonPushed(app)
            delete(app)            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Bateria';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Nodo';
            app.NumericEditFieldNodo = uieditfield(app.UIFigure, 'numeric');
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Tipo de Bateria';
            app.DropDownType = uidropdown(app.UIFigure);
            app.DropDownType.Items = {'bat_def'};
            app.DropDownType.Value = 'bat_def';
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Energia inicial';
            app.NumericEditFieldEIni = uieditfield(app.UIFigure, 'numeric');
            app.ButtonGuardar = uibutton(app.UIFigure, 'push');
            app.ButtonGuardar.ButtonPushedFcn = createCallbackFcn(app, @ButtonGuardar_ButtonPushed);
            app.ButtonGuardar.Text = 'Guardar';
            app.ButtonCancelar = uibutton(app.UIFigure, 'push');
            app.ButtonCancelar.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelar_ButtonPushed);
            app.ButtonCancelar.Text = 'Cancelar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Bateria()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end