classdef Carga < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % Carga ...
        LabelNumericEditField   matlab.ui.control.Label            % Nodo
        NumericEditFieldNodo    matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField2  matlab.ui.control.Label            % Consum...
        NumericEditFieldPc      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField3  matlab.ui.control.Label            % Consum...
        NumericEditFieldQc      matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField4  matlab.ui.control.Label            % Duraci...
        NumericEditFieldDur     matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField6  matlab.ui.control.Label            % Tope i...
        NumericEditFieldmultLow matlab.ui.control.NumericEditField % [-Inf ...
        LabelNumericEditField5  matlab.ui.control.Label            % Tope s...
        NumericEditFieldmultTop matlab.ui.control.NumericEditField % [-Inf ...
        LabelEditField          matlab.ui.control.Label            % Archiv...
        EditFieldFileU          matlab.ui.control.EditField       
        ButtonGuardar           matlab.ui.control.Button           % Guardar
        ButtonCancelar          matlab.ui.control.Button           % Cancelar
    end


    properties (Access = public)
        Carg % Description
        Principal
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % ButtonCancelar button pushed function
        function ButtonCancelar_ButtonPushed(app)
            delete(app);
        end

        % ButtonGuardar button pushed function
        function ButtonGuardar_ButtonPushed(app)
            app.Carg.nod = app.NumericEditFieldNodo.Value;
            app.Carg.pC = app.NumericEditFieldPc.Value;
            app.Carg.qC = app.NumericEditFieldQc.Value;
            app.Carg.dur = app.NumericEditFieldDur.Value;
            app.Carg.nMultipTop = app.NumericEditFieldmultTop.Value;
            app.Carg.nMultipLow = app.NumericEditFieldmultLow.Value;
            app.Carg.fileU = app.EditFieldFileU.Value;
            updateCarg(app.Principal,app.Carg);
            delete(app);            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Carga No Interrumpible';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Nodo';
            app.NumericEditFieldNodo = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Consumo Activa';
            app.NumericEditFieldPc = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'Consumo Reactiva';
            app.NumericEditFieldQc = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField4 = uilabel(app.UIFigure);
            app.LabelNumericEditField4.Text = 'Duracion (horas)';
            app.NumericEditFieldDur = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField6 = uilabel(app.UIFigure);
            app.LabelNumericEditField6.Text = 'Tope inferior (<1)';
            app.NumericEditFieldmultLow = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField5 = uilabel(app.UIFigure);
            app.LabelNumericEditField5.Text = 'Tope superior (>1)';
            app.NumericEditFieldmultTop = uieditfield(app.UIFigure, 'numeric');
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Archivo de Utilidad';
            app.EditFieldFileU = uieditfield(app.UIFigure, 'text');
            app.ButtonGuardar = uibutton(app.UIFigure, 'push');
            app.ButtonGuardar.ButtonPushedFcn = createCallbackFcn(app, @ButtonGuardar_ButtonPushed);
            app.ButtonGuardar.Text = 'Guardar';
            app.ButtonCancelar = uibutton(app.UIFigure, 'push');
            app.ButtonCancelar.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelar_ButtonPushed);
            app.ButtonCancelar.Text = 'Cancelar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Carga()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end