classdef Eolico < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % Generado...
        LabelNumericEditField matlab.ui.control.Label            % Nodo
        NumericEditFieldNodo  matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelDropDown2        matlab.ui.control.Label            % Tipo de ...
        DropDownType          matlab.ui.control.DropDown         % Dfig_200...
        LabelEditField4       matlab.ui.control.Label            % Curva de...
        EditFieldFileG        matlab.ui.control.EditField       
        LabelEditField5       matlab.ui.control.Label            % Curva de...
        EditFieldFileC        matlab.ui.control.EditField       
        ButtonGuardar         matlab.ui.control.Button           % Guardar
        ButtonCancelar        matlab.ui.control.Button           % Cancelar
    end


    properties (Access = public)
        Eol % Description
        Principal
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % ButtonGuardar button pushed function
        function ButtonGuardar_ButtonPushed(app)
            app.Eol.nod = app.NumericEditFieldNodo.Value;
            app.Eol.type = ['casos\gen\dfig\' app.DropDownType.Value '.csv'];
            app.Eol.fileG = app.EditFieldFileG.Value;
            app.Eol.fileC = app.EditFieldFileC.Value;
            updateEol(app.Principal,app.Eol);
            delete(app)
            
        end

        % ButtonCancelar button pushed function
        function ButtonCancelar_ButtonPushed(app)
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Generador Eolico';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Nodo';
            app.NumericEditFieldNodo = uieditfield(app.UIFigure, 'numeric');
            app.LabelDropDown2 = uilabel(app.UIFigure);
            app.LabelDropDown2.Text = 'Tipo de Generador';
            app.DropDownType = uidropdown(app.UIFigure);
            app.DropDownType.Items = {'Dfig_200kw', 'Dfig_250kw', 'Dfig_500kw', 'Dfig_1mw', 'Dfig_2mw'};
            app.DropDownType.Value = 'Dfig_200kw';
            app.LabelEditField4 = uilabel(app.UIFigure);
            app.LabelEditField4.Text = 'Curva de potencia';
            app.EditFieldFileG = uieditfield(app.UIFigure, 'text');
            app.LabelEditField5 = uilabel(app.UIFigure);
            app.LabelEditField5.Text = 'Curva de costos';
            app.EditFieldFileC = uieditfield(app.UIFigure, 'text');
            app.ButtonGuardar = uibutton(app.UIFigure, 'push');
            app.ButtonGuardar.ButtonPushedFcn = createCallbackFcn(app, @ButtonGuardar_ButtonPushed);
            app.ButtonGuardar.Text = 'Guardar';
            app.ButtonCancelar = uibutton(app.UIFigure, 'push');
            app.ButtonCancelar.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelar_ButtonPushed);
            app.ButtonCancelar.Text = 'Cancelar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Eolico()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end