classdef Trafo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % Transfo...
        LabelNumericEditField  matlab.ui.control.Label            % Paso
        NumericEditFieldPaso   matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % Nodo i
        NumericEditFieldNodoI  matlab.ui.control.NumericEditField % [-Inf Inf]
        Label                  matlab.ui.control.Label            % Nodo j
        NumericEditFieldNodoJ  matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField3 matlab.ui.control.Label            % Desde
        NumericEditFieldNtrLow matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField5 matlab.ui.control.Label            % Hasta
        NumericEditFieldNtrTop matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % Inicial
        NumericEditFieldNtrIni matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField6 matlab.ui.control.Label            % Costo d...
        NumericEditFieldCambio matlab.ui.control.NumericEditField % [-Inf Inf]
        ButtonGuardar          matlab.ui.control.Button           % Guardar
        ButtonCancelar         matlab.ui.control.Button           % Cancelar
    end


    properties (Access = public)
        Tranformador
        Principal
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % ButtonGuardar button pushed function
        function ButtonGuardar_ButtonPushed(app)
            app.Tranformador.N = [app.NumericEditFieldNtrLow.Value app.NumericEditFieldNtrTop.Value];
            app.Tranformador.TP = app.NumericEditFieldPaso.Value;
            app.Tranformador.nodI = app.NumericEditFieldNodoI.Value;
            app.Tranformador.nodJ = app.NumericEditFieldNodoJ.Value;
            app.Tranformador.ini = app.NumericEditFieldNtrIni.Value;
            app.Tranformador.cambio = app.NumericEditFieldCambio.Value;
            updateTrafos(app.Principal,app.Tranformador);
            delete(app)            
        end

        % ButtonCancelar button pushed function
        function ButtonCancelar_ButtonPushed(app)
            delete(app)            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Transformador';
            setAutoResize(app, app.UIFigure, true)
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Paso';
            app.NumericEditFieldPaso = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Nodo i';
            app.NumericEditFieldNodoI = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Nodo j';
            app.NumericEditFieldNodoJ = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'Desde';
            app.NumericEditFieldNtrLow = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField5 = uilabel(app.UIFigure);
            app.LabelNumericEditField5.Text = 'Hasta';
            app.NumericEditFieldNtrTop = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField4 = uilabel(app.UIFigure);
            app.LabelNumericEditField4.Text = 'Inicial';
            app.NumericEditFieldNtrIni = uieditfield(app.UIFigure, 'numeric');
            app.LabelNumericEditField6 = uilabel(app.UIFigure);
            app.LabelNumericEditField6.Text = 'Costo de cambio';
            app.NumericEditFieldCambio = uieditfield(app.UIFigure, 'numeric');
            app.ButtonGuardar = uibutton(app.UIFigure, 'push');
            app.ButtonGuardar.ButtonPushedFcn = createCallbackFcn(app, @ButtonGuardar_ButtonPushed);
            app.ButtonGuardar.Text = 'Guardar';
            app.ButtonCancelar = uibutton(app.UIFigure, 'push');
            app.ButtonCancelar.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelar_ButtonPushed);
            app.ButtonCancelar.Text = 'Cancelar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Trafo()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end